classdef Audio_processing_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlayInversedButton      matlab.ui.control.Button
        ClearsoundButton        matlab.ui.control.Button
        PlayeditedButton        matlab.ui.control.Button
        DurationEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel  matlab.ui.control.Label
        SpeedKnob               matlab.ui.control.Knob
        SpeedLabel              matlab.ui.control.Label
        AmptitudeKnob           matlab.ui.control.Knob
        AmptitudeKnobLabel      matlab.ui.control.Label
        PlayOriginalButton      matlab.ui.control.Button
        RecordButton            matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            recorder = audiorecorder(44100,16,2);
            duration = app.DurationEditField.Value;
            msgbox("Recording started");
            recordblocking(recorder, duration);
            msgbox("Recording finished");
            y = getaudiodata(recorder);
            audiowrite('audio_file.wav', y, recorder.SampleRate)
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            [y, Fs] = audioread('audio_file.wav');
            sound(y, Fs);
t = (0:length(y)-1)/Fs;
plot(app.UIAxes, t, y);
title(app.UIAxes,'Audio samples as a function of time')
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Amplitude');
Y = fft(y);
f = linspace(-Fs/2, Fs/2, length(Y));
f = fftshift(f);
plot(app.UIAxes2,f, abs(Y));
app.UIAxes2.Title.FontSize = 10;
title(app.UIAxes2,'Transformed data as a function of frequency by Fourier transform')
xlabel(app.UIAxes2,'Frequency (Hz)');
ylabel(app.UIAxes2,'Magnitude');
        end

        % Button pushed function: PlayeditedButton
        function PlayeditedButtonPushed(app, event)
            [y,Fs] = audioread("audio_file.wav");
            speed = app.SpeedKnob.Value;
            magnitude = app.AmptitudeKnob.Value;
            y = y * magnitude;
            Fs = Fs * speed;
            sound(y,Fs);
            t = (0:length(y)-1)/Fs;
            plot(app.UIAxes, t, y);
            title(app.UIAxes,'Audio samples as a function of time')
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            Y = fft(y);
            f = linspace(-Fs/2, Fs/2, length(Y));
            f = fftshift(f);
            plot(app.UIAxes2,f, abs(Y));
            app.UIAxes2.Title.FontSize = 10;
            title(app.UIAxes2,'Transformed data as a function of frequency by Fourier transform')
            xlabel(app.UIAxes2,'Frequency (Hz)');
            ylabel(app.UIAxes2,'Magnitude');
        end

        % Button pushed function: ClearsoundButton
        function ClearsoundButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: PlayInversedButton
        function PlayInversedButtonPushed(app, event)
            [x,Fs] = audioread("audio_file.wav")   ; 
                y = flipud(x);
                speed = app.SpeedKnob.Value;
                magnitude = app.AmptitudeKnob.Value;
                y = y * magnitude;
                Fs = Fs * speed;
                sound(y,Fs)
                t = (0:length(y)-1)/Fs;
                plot(app.UIAxes, t, y);
                title(app.UIAxes,'Audio samples as a function of time')
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
                Y = fft(y);
                f = linspace(-Fs/2, Fs/2, length(Y));
                f = fftshift(f);
                plot(app.UIAxes2,f, abs(Y));
                app.UIAxes2.Title.FontSize = 10;
                title(app.UIAxes2,'Transformed data as a function of frequency by Fourier transform')
                xlabel(app.UIAxes2,'Frequency (Hz)');
                ylabel(app.UIAxes2,'Magnitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Audio graph changed')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.AmptitudeKnobLabel = uilabel(app.UIFigure);
            app.AmptitudeKnobLabel.Text = 'Amptitude';
            app.AmptitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmptitudeKnob.Limits = [-25 25];
            app.AmptitudeKnob.Value = 1;
            app.SpeedLabel = uilabel(app.UIFigure);
            app.SpeedLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedKnob.Limits = [0 2];
            app.SpeedKnob.MajorTicks = [0 0.5 1 1.5 2];
            app.SpeedKnob.Value = 1;
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.Value = 5;
            app.PlayeditedButton = uibutton(app.UIFigure, 'push');
            app.PlayeditedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayeditedButtonPushed, true);
            app.PlayeditedButton.Text = 'Play edited';
            app.ClearsoundButton = uibutton(app.UIFigure, 'push');
            app.ClearsoundButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsoundButtonPushed, true);
            app.ClearsoundButton.Text = 'Clear sound';
            app.PlayInversedButton = uibutton(app.UIFigure, 'push');
            app.PlayInversedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayInversedButtonPushed, true);
            app.PlayInversedButton.Text = 'Play Inversed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_processing_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end